classdef ImageProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RotateanticlockwiseButton   matlab.ui.control.Button
        RotateClockwiseButton       matlab.ui.control.Button
        EdgeDetectionButton         matlab.ui.control.Button
        ComplementImageButton       matlab.ui.control.Button
        HistogramButton             matlab.ui.control.Button
        AdvancedOperationsLabel     matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        ConverttoBinaryImageButton  matlab.ui.control.Button
        RGBtoGreyButton             matlab.ui.control.Button
        UploadImageButton           matlab.ui.control.Button
        BasicOperationsLabel        matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            a = uigetfile('.jpg');
            a = imread(a);
            axes(handles.axes1);
            imshow(a);
            setappdata(0,'a',a);
            a = uigetfile('.jpg');
            a = imread(a);
            axes(handles.axes1);
            imshow(a);
            setappdata(0,'a',a);
        end

        % Button pushed function: RGBtoGreyButton
        function RGBtoGreyButtonPushed(app, event)
            a = getappdata(0,'a');
            agray = rgb2gray(a);
            axes(handles.axes1);
            imshow(agray);
        end

        % Button pushed function: ConverttoBinaryImageButton
        function ConverttoBinaryImageButtonPushed(app, event)
            a = getappdata(0,'a');
            abw = imbinarize(a);
            axes(handles.axes1);
            imshow(abw);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            a = getappdata(0,'a');
            axes(handles.axes1);
            imshow(a);
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            a = getappdata(0,'a');
            ahist = a;
            ahist = rgb2gray(ahist);
            axes(handles.axes1);
            imhist(ahist);
        end

        % Button pushed function: ComplementImageButton
        function ComplementImageButtonPushed(app, event)
            a = getappdata(0,'a');
            acomp = a;
            acomp = incomplement(acomp);
            axes(handles.axes1);
            imshow(acomp);
        end

        % Button pushed function: EdgeDetectionButton
        function EdgeDetectionButtonPushed(app, event)
            a = getappdata(0,'a');
            aedge = a;
            aedge = rgb2gray(aedge);
            aedge = edge(aedge,'Canny');
            axes(handles.axes1);
            imshow(aedge);
        end

        % Button pushed function: RotateClockwiseButton
        function RotateClockwiseButtonPushed(app, event)
            a = getappdata(0,'a');
            aclock = a;
            aclock = imrotate(aclock, 270);
            axes(handles.axes1);
            imshow(aclock);
        end

        % Button pushed function: RotateanticlockwiseButton
        function RotateanticlockwiseButtonPushed(app, event)
            a = getappdata(0, 'a');
            aclock = a;
            aclock = imrotate(aclock,90);
            axes(handles.axes1);
            imshow(aclock);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BasicOperationsLabel = uilabel(app.UIFigure);
            app.BasicOperationsLabel.Text = 'Basic Operations:';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.RGBtoGreyButton = uibutton(app.UIFigure, 'push');
            app.RGBtoGreyButton.ButtonPushedFcn = createCallbackFcn(app, @RGBtoGreyButtonPushed, true);
            app.RGBtoGreyButton.Text = 'RGB to Grey';
            app.ConverttoBinaryImageButton = uibutton(app.UIFigure, 'push');
            app.ConverttoBinaryImageButton.ButtonPushedFcn = createCallbackFcn(app, @ConverttoBinaryImageButtonPushed, true);
            app.ConverttoBinaryImageButton.Text = 'Convert to Binary Image';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.AdvancedOperationsLabel = uilabel(app.UIFigure);
            app.AdvancedOperationsLabel.Text = 'Advanced Operations:';
            app.HistogramButton = uibutton(app.UIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.ComplementImageButton = uibutton(app.UIFigure, 'push');
            app.ComplementImageButton.ButtonPushedFcn = createCallbackFcn(app, @ComplementImageButtonPushed, true);
            app.ComplementImageButton.Text = 'Complement Image';
            app.EdgeDetectionButton = uibutton(app.UIFigure, 'push');
            app.EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectionButtonPushed, true);
            app.EdgeDetectionButton.Text = 'Edge Detection';
            app.RotateClockwiseButton = uibutton(app.UIFigure, 'push');
            app.RotateClockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @RotateClockwiseButtonPushed, true);
            app.RotateClockwiseButton.Text = 'Rotate Clockwise';
            app.RotateanticlockwiseButton = uibutton(app.UIFigure, 'push');
            app.RotateanticlockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @RotateanticlockwiseButtonPushed, true);
            app.RotateanticlockwiseButton.Text = 'Rotate anti-clockwise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end